classdef gui_rosma < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ROSMADATASETLabel        matlab.ui.control.Label
        Panel                    matlab.ui.container.Panel
        BrowseButton             matlab.ui.control.Button
        Label_4                  matlab.ui.control.Label
        PathField                matlab.ui.control.EditField
        BrowsetoDataFileLabel    matlab.ui.control.Label
        ROSCheckBox              matlab.ui.control.CheckBox
        UIAxes                   matlab.ui.control.UIAxes
        UIAxes2                  matlab.ui.control.UIAxes
        play                     matlab.ui.control.Image
        pause                    matlab.ui.control.Image
        stop                     matlab.ui.control.Image
        MTMRLabel                matlab.ui.control.Label
        MTMLLabel                matlab.ui.control.Label
        Label_5                  matlab.ui.control.Label
        Label_6                  matlab.ui.control.Label
        PSM1Label                matlab.ui.control.Label
        PSM2Label                matlab.ui.control.Label
        Label_7                  matlab.ui.control.Label
        Label_8                  matlab.ui.control.Label
        TimesSliderLabel         matlab.ui.control.Label
        TimesSlider              matlab.ui.control.Slider
        TimestampEditFieldLabel  matlab.ui.control.Label
        TimestampEditField       matlab.ui.control.EditField
        FrameEditFieldLabel      matlab.ui.control.Label
        FrameEditField           matlab.ui.control.NumericEditField
        Image2                   matlab.ui.control.Image
        Image3                   matlab.ui.control.Image
        Label                    matlab.ui.control.Label
        Label_3                  matlab.ui.control.Label
        ROSINFOPublishingDatatoTopicsTextAreaLabel  matlab.ui.control.Label
        ROSINFOPublishingDatatoTopicsTextArea  matlab.ui.control.TextArea
    end

   
    properties (Access = private)
        data = struct;
        filename;
        path;
        n;
        on = true; 
        i = 1; 
    end
       
    methods (Access = private)
             
        function initialization(app)
            app.filename = '';
            app.path = '';
            app.PathField.Value = '';
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            addpath("media/")
            app.play.ImageSource = [pwd '/media/play.png'];
            app.pause.ImageSource = [pwd '/media/pause.png'];
            app.stop.ImageSource = [pwd '/media/stop.png'];
            app.Image2.ImageSource = [pwd '/media/logo_santanna.png'];
            app.Image3.ImageSource = [pwd '/media/logo_uma.jpg'];
            app.initialization();
            
            if (app.ROSCheckBox.Value)
                if ~ros.internal.Global.isNodeActive           
                    rosinit;
                end
            end
                                  
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Callback function
        function DELETEButtonPushed(app, event)
            rosshutdown
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [app.filename,app.path] = uigetfile('*.csv');
            figure(app.UIFigure)
            app.PathField.Value = app.path;
            app.TimestampEditField.Value = '';
            app.FrameEditField.Value = 0;

            rawData = readtable(app.filename);
            app.data.info = app.filename;
            app.data.timestamp = rawData.Date;
            
            % MTML
            app.data.MTML.position = [rawData.MTML_position_x rawData.MTML_position_y rawData.MTML_position_z];
            app.data.MTML.orientation = [rawData.MTML_orientation_x rawData.MTML_orientation_y rawData.MTML_orientation_z rawData.MTML_orientation_w];
            app.data.MTML.velocity_linear = [rawData.MTML_velocity_linear_x rawData.MTML_velocity_linear_y rawData.MTML_velocity_linear_z];
            app.data.MTML.velocity_angular = [rawData.MTML_velocity_angular_x rawData.MTML_velocity_angular_y rawData.MTML_velocity_angular_z];
            app.data.MTML.wrench_force = [rawData.MTML_wrench_force_x rawData.MTML_wrench_force_y rawData.MTML_wrench_force_z];
            app.data.MTML.wrench_torque = [rawData.MTML_wrench_torque_x rawData.MTML_wrench_torque_y rawData.MTML_wrench_torque_z];
            app.data.MTML.joints_position = [rawData.MTML_joint_position_1 rawData.MTML_joint_position_2 rawData.MTML_joint_position_3,...
                rawData.MTML_joint_position_4 rawData.MTML_joint_position_5 rawData.MTML_joint_position_6 rawData.MTML_joint_position_7];
            app.data.MTML.joints_velocity = [rawData.MTML_joint_velocity_1 rawData.MTML_joint_velocity_2 rawData.MTML_joint_velocity_3,...
                rawData.MTML_joint_velocity_4 rawData.MTML_joint_velocity_5 rawData.MTML_joint_velocity_6 rawData.MTML_joint_velocity_7];
            app.data.MTML.joints_effort = [rawData.MTML_joint_effort_1 rawData.MTML_joint_effort_2 rawData.MTML_joint_effort_3,...
                rawData.MTML_joint_effort_4 rawData.MTML_joint_effort_5 rawData.MTML_joint_effort_6 rawData.MTML_joint_effort_7];
            
            % MTMR
            app.data.MTMR.position = [rawData.MTMR_position_x rawData.MTMR_position_y rawData.MTMR_position_z];
            app.data.MTMR.orientation = [rawData.MTMR_orientation_x rawData.MTMR_orientation_y rawData.MTMR_orientation_z rawData.MTMR_orientation_w];
            app.data.MTMR.velocity_linear = [rawData.MTMR_velocity_linear_x rawData.MTMR_velocity_linear_y rawData.MTMR_velocity_linear_z];
            app.data.MTMR.velocity_angular = [rawData.MTMR_velocity_angular_x rawData.MTMR_velocity_angular_y rawData.MTMR_velocity_angular_z];
            app.data.MTMR.wrench_force = [rawData.MTMR_wrench_force_x rawData.MTMR_wrench_force_y rawData.MTMR_wrench_force_z];
            app.data.MTMR.wrench_torque = [rawData.MTMR_wrench_torque_x rawData.MTMR_wrench_torque_y rawData.MTMR_wrench_torque_z];
            app.data.MTMR.joints_position = [rawData.MTMR_joint_position_1 rawData.MTMR_joint_position_2 rawData.MTMR_joint_position_3,...
                rawData.MTMR_joint_position_4 rawData.MTMR_joint_position_5 rawData.MTMR_joint_position_6 rawData.MTMR_joint_position_7];
            app.data.MTMR.joints_velocity = [rawData.MTMR_joint_velocity_1 rawData.MTMR_joint_velocity_2 rawData.MTMR_joint_velocity_3,...
                rawData.MTMR_joint_velocity_4 rawData.MTMR_joint_velocity_5 rawData.MTMR_joint_velocity_6 rawData.MTMR_joint_velocity_7];
            app.data.MTMR.joints_effort = [rawData.MTMR_joint_effort_1 rawData.MTMR_joint_effort_2 rawData.MTMR_joint_effort_3,...
                rawData.MTMR_joint_effort_4 rawData.MTMR_joint_effort_5 rawData.MTMR_joint_effort_6 rawData.MTMR_joint_effort_7];
            
            % PSM1
            app.data.PSM1.position = [rawData.PSM1_position_x rawData.PSM1_position_y rawData.PSM1_position_z];
            app.data.PSM1.orientation = [rawData.PSM1_orientation_x rawData.PSM1_orientation_y rawData.PSM1_orientation_z rawData.PSM1_orientation_w];
            app.data.PSM1.velocity_linear = [rawData.PSM1_velocity_linear_x rawData.PSM1_velocity_linear_y rawData.PSM1_velocity_linear_z];
            app.data.PSM1.velocity_angular = [rawData.PSM1_velocity_angular_x rawData.PSM1_velocity_angular_y rawData.PSM1_velocity_angular_z];
            app.data.PSM1.wrench_force = [rawData.PSM1_wrench_force_x rawData.PSM1_wrench_force_y rawData.PSM1_wrench_force_z];
            app.data.PSM1.wrench_torque = [rawData.PSM1_wrench_torque_x rawData.PSM1_wrench_torque_y rawData.PSM1_wrench_torque_z];
            app.data.PSM1.joints_position = [rawData.PSM1_joint_position_1 rawData.PSM1_joint_position_2 rawData.PSM1_joint_position_3,...
                rawData.PSM1_joint_position_4 rawData.PSM1_joint_position_5 rawData.PSM1_joint_position_6 rawData.PSM1_joint_position_7];
            app.data.PSM1.joints_velocity = [rawData.PSM1_joint_velocity_1 rawData.PSM1_joint_velocity_2 rawData.PSM1_joint_velocity_3,...
                rawData.PSM1_joint_velocity_4 rawData.PSM1_joint_velocity_5 rawData.PSM1_joint_velocity_6 rawData.PSM1_joint_velocity_7];
            app.data.PSM1.joints_effort = [rawData.PSM1_joint_effort_1 rawData.PSM1_joint_effort_2 rawData.PSM1_joint_effort_3,...
                rawData.PSM1_joint_effort_4 rawData.PSM1_joint_effort_5 rawData.PSM1_joint_effort_6 rawData.PSM1_joint_effort_7];
            
            % PSM2
            app.data.PSM2.position = [rawData.PSM2_position_x rawData.PSM2_position_y rawData.PSM2_position_z];
            app.data.PSM2.orientation = [rawData.PSM2_orientation_x rawData.PSM2_orientation_y rawData.PSM2_orientation_z rawData.PSM2_orientation_w];
            app.data.PSM2.velocity_linear = [rawData.PSM2_velocity_linear_x rawData.PSM2_velocity_linear_y rawData.PSM2_velocity_linear_z];
            app.data.PSM2.velocity_angular = [rawData.PSM2_velocity_angular_x rawData.PSM2_velocity_angular_y rawData.PSM2_velocity_angular_z];
            app.data.PSM2.wrench_force = [rawData.PSM2_wrench_force_x rawData.PSM2_wrench_force_y rawData.PSM2_wrench_force_z];
            app.data.PSM2.wrench_torque = [rawData.PSM2_wrench_torque_x rawData.PSM2_wrench_torque_y rawData.PSM2_wrench_torque_z];
            app.data.PSM2.joints_position = [rawData.PSM2_joint_position_1 rawData.PSM2_joint_position_2 rawData.PSM2_joint_position_3,...
                rawData.PSM2_joint_position_4 rawData.PSM2_joint_position_5 rawData.PSM2_joint_position_6 rawData.PSM2_joint_position_7];
            app.data.PSM2.joints_velocity = [rawData.PSM2_joint_velocity_1 rawData.PSM2_joint_velocity_2 rawData.PSM2_joint_velocity_3,...
                rawData.PSM2_joint_velocity_4 rawData.PSM2_joint_velocity_5 rawData.PSM2_joint_velocity_6 rawData.PSM2_joint_velocity_7];
            app.data.PSM2.joints_effort = [rawData.PSM2_joint_effort_1 rawData.PSM2_joint_effort_2 rawData.PSM2_joint_effort_3,...
                rawData.PSM2_joint_effort_4 rawData.PSM2_joint_effort_5 rawData.PSM2_joint_effort_6 rawData.PSM2_joint_effort_7];
            
            data = app.data;
            save('data.mat','data')
            
            % Setting figures perspective
             cla(app.UIAxes)
             app.UIAxes.XLim = [min(min(app.data.MTML.position(:,1)),min(app.data.MTMR.position(:,1))) max(max(app.data.MTML.position(:,1)),max(app.data.MTMR.position(:,1)))];
             app.UIAxes.YLim = [min(min(app.data.MTML.position(:,2)),min(app.data.MTMR.position(:,2))) max(max(app.data.MTML.position(:,2)),max(app.data.MTMR.position(:,2)))];
             app.UIAxes.ZLim = [min(min(app.data.MTML.position(:,3)),min(app.data.MTMR.position(:,3))) max(max(app.data.MTML.position(:,3)),max(app.data.MTMR.position(:,3)))];
             app.UIAxes.ZLabel.String = 'Z (m)';
             cla(app.UIAxes2)
             app.UIAxes2.XLim = [min(min(app.data.PSM1.position(:,1)),min(app.data.PSM2.position(:,1))) max(max(app.data.PSM1.position(:,1)),max(app.data.PSM2.position(:,1)))];
             app.UIAxes2.YLim = [min(min(app.data.PSM1.position(:,2)),min(app.data.PSM2.position(:,2))) max(max(app.data.PSM1.position(:,2)),max(app.data.PSM2.position(:,2)))];
             app.UIAxes2.ZLim = [min(min(app.data.PSM1.position(:,3)),min(app.data.PSM2.position(:,3))) max(max(app.data.PSM1.position(:,3)),max(app.data.PSM2.position(:,3)))];
             app.UIAxes2.ZLabel.String = 'Z (m)';
                          
            app.i = 1;   
            app.n = length(app.data.MTML.position)-1;  
            app.TimesSlider.Limits = [0 app.n*0.02];
            app.TimesSlider.MajorTicks = [0 app.n*0.02];
            app.TimesSlider.MajorTickLabels = {'1',num2str(app.n*0.02)};
        end

        % Image clicked function: play
        function playImageClicked(app, event)
%             % Setting figures perspective
%              cla(app.UIAxes)
%              app.UIAxes.XLim = [min(min(app.data.MTML.position(:,1)),min(app.data.MTMR.position(:,1))) max(max(app.data.MTML.position(:,1)),max(app.data.MTMR.position(:,1)))];
%              app.UIAxes.YLim = [min(min(app.data.MTML.position(:,2)),min(app.data.MTMR.position(:,2))) max(max(app.data.MTML.position(:,2)),max(app.data.MTMR.position(:,2)))];
%              app.UIAxes.ZLim = [min(min(app.data.MTML.position(:,3)),min(app.data.MTMR.position(:,3))) max(max(app.data.MTML.position(:,3)),max(app.data.MTMR.position(:,3)))];
%              app.UIAxes.ZLabel.String = 'Z (m)';
%              cla(app.UIAxes2)
%              app.UIAxes2.XLim = [min(min(app.data.PSM1.position(:,1)),min(app.data.PSM2.position(:,1))) max(max(app.data.PSM1.position(:,1)),max(app.data.PSM2.position(:,1)))];
%              app.UIAxes2.YLim = [min(min(app.data.PSM1.position(:,2)),min(app.data.PSM2.position(:,2))) max(max(app.data.PSM1.position(:,2)),max(app.data.PSM2.position(:,2)))];
%              app.UIAxes2.ZLim = [min(min(app.data.PSM1.position(:,3)),min(app.data.PSM2.position(:,3))) max(max(app.data.PSM1.position(:,3)),max(app.data.PSM2.position(:,3)))];
%              app.UIAxes2.ZLabel.String = 'Z (m)';
             
             if (app.ROSCheckBox.Value)
              app.ROSINFOPublishingDatatoTopicsTextArea.Visible = 'on';
              app.ROSINFOPublishingDatatoTopicsTextAreaLabel.Visible = 'on';
              msg_psm1 = rosmessage(rostype.sensor_msgs_JointState);
              msg_psm2 = rosmessage(rostype.sensor_msgs_JointState);
              msg_mtmr = rosmessage(rostype.sensor_msgs_JointState);
              msg_mtml = rosmessage(rostype.sensor_msgs_JointState);
    
              pub_psm1 = rospublisher('/dvrk/PSM1/set_position_goal_joint', rostype.sensor_msgs_JointState);
              pub_psm2 = rospublisher('/dvrk/PSM2/set_position_goal_joint', rostype.sensor_msgs_JointState);
              pub_mtmr = rospublisher('/dvrk/MTMR/set_position_goal_joint', rostype.sensor_msgs_JointState);
              pub_mtml = rospublisher('/dvrk/MTML/set_position_goal_joint', rostype.sensor_msgs_JointState);
             end
             
            app.on = true;   
            hold(app.UIAxes,'on')
            hold(app.UIAxes2,'on')  
            while (app.i<app.n && app.on)
                plot3(app.UIAxes,app.data.MTML.position(app.i,1),app.data.MTML.position(app.i,2),app.data.MTML.position(app.i,3),'b.'); 
                plot3(app.UIAxes,app.data.MTMR.position(app.i,1),app.data.MTMR.position(app.i,2),app.data.MTMR.position(app.i,3),'r.'); 
                plot3(app.UIAxes2,app.data.PSM1.position(app.i,1),app.data.PSM1.position(app.i,2),app.data.PSM1.position(app.i,3),'r.'); 
                plot3(app.UIAxes2,app.data.PSM2.position(app.i,1),app.data.PSM2.position(app.i,2),app.data.PSM2.position(app.i,3),'b.'); 
                drawnow    
                app.i = app.i+1;
                app.TimesSlider.Value = app.i*0.02;
                app.TimestampEditField.Value = app.data.timestamp{app.i}(12:end);
                app.FrameEditField.Value = app.i;
                
                if (app.ROSCheckBox.Value)
                  msg_psm1.Position = app.data.PSM1.joints_position(app.i,1:6);               
                  msg_psm2.Position = app.data.PSM2.joints_position(app.i,1:6);                   
                  msg_mtmr.Position = app.data.MTMR.joints_position(app.i,:);
                  msg_mtml.Position = app.data.MTML.joints_position(app.i,:);
                  
                  send(pub_psm1, msg_psm1);
                  send(pub_psm2, msg_psm2); 
                  send(pub_mtmr, msg_mtmr);
                  send(pub_mtml, msg_mtml);
                end
                pause(0.02)  
            end                      
        end

        % Image clicked function: pause
        function pauseImageClicked(app, event)
            app.on = false;
            app.ROSINFOPublishingDatatoTopicsTextArea.Visible = 'off';
            app.ROSINFOPublishingDatatoTopicsTextAreaLabel.Visible = 'off';
        end

        % Image clicked function: stop
        function stopImageClicked(app, event)
            app.on = false;
            app.i = 1;
            plot3(app.UIAxes,app.data.MTML.position(:,1),app.data.MTML.position(:,2),app.data.MTML.position(:,3),'b.')
            plot3(app.UIAxes,app.data.MTMR.position(:,1),app.data.MTMR.position(:,2),app.data.MTMR.position(:,3),'r.')
            plot3(app.UIAxes2,app.data.PSM1.position(:,1),app.data.PSM1.position(:,2),app.data.PSM1.position(:,3),'r.')
            plot3(app.UIAxes2,app.data.PSM2.position(:,1),app.data.PSM2.position(:,2),app.data.PSM2.position(:,3),'b.')
            app.TimestampEditField.Value = app.data.timestamp{end-1}(12:end);
            app.FrameEditField.Value = app.n;
            disp('End')
            app.ROSINFOPublishingDatatoTopicsTextArea.Visible = 'off';
            app.ROSINFOPublishingDatatoTopicsTextAreaLabel.Visible = 'off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ROSMADATASETLabel = uilabel(app.UIFigure);
            app.ROSMADATASETLabel.Text = 'ROSMA DATASET';
            app.Panel = uipanel(app.UIFigure);
            app.BrowseButton = uibutton(app.Panel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = {'Browse '; ''};
            app.Label_4 = uilabel(app.Panel);
            app.Label_4.Text = '';
            app.PathField = uieditfield(app.Panel, 'text');
            app.BrowsetoDataFileLabel = uilabel(app.Panel);
            app.BrowsetoDataFileLabel.Text = 'Browse to Data File:';
            app.ROSCheckBox = uicheckbox(app.Panel);
            app.ROSCheckBox.Text = ' ROS';
            app.ROSCheckBox.Value = true;
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, 'Master Tool Manipulators Position')
            xlabel(app.UIAxes, 'X (m)')
            ylabel(app.UIAxes, 'Y (m)')
            app.UIAxes2 = uiaxes(app.Panel);
            title(app.UIAxes2, 'Patient Side Manipulators Position')
            xlabel(app.UIAxes2, 'X (m)')
            ylabel(app.UIAxes2, 'Y (m)')
            app.play = uiimage(app.Panel);
            app.play.ImageClickedFcn = createCallbackFcn(app, @playImageClicked, true);
            app.pause = uiimage(app.Panel);
            app.pause.ImageClickedFcn = createCallbackFcn(app, @pauseImageClicked, true);
            app.stop = uiimage(app.Panel);
            app.stop.ImageClickedFcn = createCallbackFcn(app, @stopImageClicked, true);
            app.MTMRLabel = uilabel(app.Panel);
            app.MTMRLabel.Text = 'MTMR';
            app.MTMLLabel = uilabel(app.Panel);
            app.MTMLLabel.Text = 'MTML';
            app.Label_5 = uilabel(app.Panel);
            app.Label_5.Text = '';
            app.Label_6 = uilabel(app.Panel);
            app.Label_6.Text = '';
            app.PSM1Label = uilabel(app.Panel);
            app.PSM1Label.Text = 'PSM1';
            app.PSM2Label = uilabel(app.Panel);
            app.PSM2Label.Text = 'PSM2';
            app.Label_7 = uilabel(app.Panel);
            app.Label_7.Text = '';
            app.Label_8 = uilabel(app.Panel);
            app.Label_8.Text = '';
            app.TimesSliderLabel = uilabel(app.Panel);
            app.TimesSliderLabel.Text = 'Time (s)';
            app.TimesSlider = uislider(app.Panel);
            app.TimesSlider.MajorTicks = [];
            app.TimesSlider.MajorTickLabels = {''};
            app.TimesSlider.MinorTicks = [];
            app.TimesSlider.Enable = 'off';
            app.TimestampEditFieldLabel = uilabel(app.Panel);
            app.TimestampEditFieldLabel.Text = 'Timestamp:';
            app.TimestampEditField = uieditfield(app.Panel, 'text');
            app.FrameEditFieldLabel = uilabel(app.Panel);
            app.FrameEditFieldLabel.Text = 'Frame:';
            app.FrameEditField = uieditfield(app.Panel, 'numeric');
            app.Image2 = uiimage(app.UIFigure);
            app.Image3 = uiimage(app.UIFigure);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Authors: Irene Rivas-Blanco (irivas@uma.es) and Carlos Pérez-del-Pulgar (carlosperez@uma.es) - University of Malaga (Spain)';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.ROSINFOPublishingDatatoTopicsTextAreaLabel = uilabel(app.UIFigure);
            app.ROSINFOPublishingDatatoTopicsTextAreaLabel.Visible = 'off';
            app.ROSINFOPublishingDatatoTopicsTextAreaLabel.Text = '[ROS INFO] Publishing Data to Topics:';
            app.ROSINFOPublishingDatatoTopicsTextArea = uitextarea(app.UIFigure);
            app.ROSINFOPublishingDatatoTopicsTextArea.Visible = 'off';
            app.ROSINFOPublishingDatatoTopicsTextArea.Value = {'/dvrk/PSM1/set_position_goal_joint'; '/dvrk/PSM2/set_position_goal_joint'; '/dvrk/MTMR/set_position_goal_joint'; '/dvrk/MTML/set_position_goal_joint'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui_rosma

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end